classdef SerialConterTesterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        JointSelectionButtonGroup    matlab.ui.container.ButtonGroup
        XaxisButton                  matlab.ui.control.ToggleButton
        YaxisButton                  matlab.ui.control.ToggleButton
        RotationButton               matlab.ui.control.ToggleButton
        IndenterButton               matlab.ui.control.ToggleButton
        OnOffButton                  matlab.ui.control.Button
        SetVelocitySliderLabel       matlab.ui.control.Label
        SetVelocitySlider            matlab.ui.control.Slider
        StepsDistanceEditFieldLabel  matlab.ui.control.Label
        StepsDistanceEditField       matlab.ui.control.NumericEditField
        GOButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        serial_port = 'COM12';
        baud_rate = 9600;
        jointSelected = 1;
        joints = [1 2 3 4]';
        steps = 0;              %distance or steps input
        speed = 0;              %input designated motor speed
        s


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                    s = serialport("COM12",9600);

        end

        % Selection changed function: JointSelectionButtonGroup
        function JointSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.JointSelectionButtonGroup.SelectedObject;
            if selectedButton.Text == "Xaxis"
                app.jointSelected = app.joints(1);
            elseif selectedButton.Text == "Yaxis"
                app.jointSelected = app.joints(2);
            elseif selectedButton.Text == "Rotation"
                app.jointSelected = app.joints(3);
            elseif selectedButton.Text == "Indenter"
                app.jointSelected = app.joints(4);
            end
        end

        % Value changed function: StepsDistanceEditField
        function StepsDistanceEditFieldValueChanged(app, event)
            app.steps = app.StepsDistanceEditField.Value;
            
        end

        % Value changed function: SetVelocitySlider
        function SetVelocitySliderValueChanged(app, event)
            app.speed = app.SetVelocitySlider.Value;
            
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            motorComm = sprintf("m%d",app.jointSelected);
            spetComm = sprintf("s%d",app.steps);
            speedComm = sprintf("v%d",app.speed);
            commToSend = strcat(motorComm,spetComm,speedComm)
            writeline(app.s,commToSend);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.JointSelectionButtonGroup = uibuttongroup(app.UIFigure);
            app.JointSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @JointSelectionButtonGroupSelectionChanged, true);
            app.JointSelectionButtonGroup.Title = 'Joint Selection';
            app.XaxisButton = uitogglebutton(app.JointSelectionButtonGroup);
            app.XaxisButton.Text = 'Xaxis';
            app.XaxisButton.Value = true;
            app.YaxisButton = uitogglebutton(app.JointSelectionButtonGroup);
            app.YaxisButton.Text = 'Yaxis';
            app.RotationButton = uitogglebutton(app.JointSelectionButtonGroup);
            app.RotationButton.Text = 'Rotation';
            app.IndenterButton = uitogglebutton(app.JointSelectionButtonGroup);
            app.IndenterButton.Text = 'Indenter';
            app.OnOffButton = uibutton(app.UIFigure, 'push');
            app.OnOffButton.Text = 'OnOff';
            app.SetVelocitySliderLabel = uilabel(app.UIFigure);
            app.SetVelocitySliderLabel.Text = 'Set Velocity';
            app.SetVelocitySlider = uislider(app.UIFigure);
            app.SetVelocitySlider.ValueChangedFcn = createCallbackFcn(app, @SetVelocitySliderValueChanged, true);
            app.StepsDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.StepsDistanceEditFieldLabel.Text = 'Steps(Distance)';
            app.StepsDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @StepsDistanceEditFieldValueChanged, true);
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialConterTesterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end