classdef SerialConterTesterApp_copy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        JointSelectionButtonGroup    matlab.ui.container.ButtonGroup
        XaxisButton                  matlab.ui.control.ToggleButton
        YaxisButton                  matlab.ui.control.ToggleButton
        RotationButton               matlab.ui.control.ToggleButton
        IndenterButton               matlab.ui.control.ToggleButton
        OnOffButton                  matlab.ui.control.Button
        SetSpeedSliderLabel          matlab.ui.control.Label
        SetSpeedSlider               matlab.ui.control.Slider
        StepsDistanceEditFieldLabel  matlab.ui.control.Label
        StepsDistanceEditField       matlab.ui.control.NumericEditField
        GOButton                     matlab.ui.control.Button
        ListBoxLabel                 matlab.ui.control.Label
        COMListBox                   matlab.ui.control.ListBox
        StartButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        serial_port;
        baud_rate = 9600;
        jointSelected = 1;
        joints = [1 2 3 4]';
        steps = 0;              %distance or steps input
        speed = 0;              %input designated motor speed
        
        s;
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            freeports = serialportlist("available");
            app.COMListBox.Items = freeports;
            app.COMListBoxValueChanged();
            
        end

        % Selection changed function: JointSelectionButtonGroup
        function JointSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.JointSelectionButtonGroup.SelectedObject;
            if selectedButton.Text == "Xaxis"
                app.jointSelected = app.joints(1);
            elseif selectedButton.Text == "Yaxis"
                app.jointSelected = app.joints(2);
            elseif selectedButton.Text == "Rotation"
                app.jointSelected = app.joints(3);
            elseif selectedButton.Text == "Indenter"
                app.jointSelected = app.joints(4);
            end
        end

        % Value changed function: StepsDistanceEditField
        function StepsDistanceEditFieldValueChanged(app, event)
            app.steps = app.StepsDistanceEditField.Value;
            
        end

        % Value changed function: SetSpeedSlider
        function SetSpeedSliderValueChanged(app, event)
            app.speed = app.SetSpeedSlider.Value;
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            motorComm = sprintf("m%d",app.jointSelected);
            spetComm = sprintf("s%d",app.steps);
            speedComm = sprintf("v%d",ceil(app.speed));
            commToSend = strcat(motorComm,spetComm,speedComm)
            writeline(app.s,commToSend);
            %fprintf(app.s,commToSend);
            %commToSend
            %fscanf(app.s);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %close(app.s);
            %delete(app.s);
            delete(app);
        end

        % Value changed function: COMListBox
        function COMListBoxValueChanged(app, event)
            app.serial_port = app.COMListBox.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.s = serialport(app.serial_port,app.baud_rate)
            %app.s = serial(com,'BaudRate',9600,'Terminator',10,'Timeout',5);
            %fopen(app.s);
        end

        % Button pushed function: OnOffButton
        function OnOffButtonPushed(app, event)
            motorComm = sprintf("m0");
            commToSend = strcat(motorComm);
            writeline(app.s,commToSend);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.JointSelectionButtonGroup = uibuttongroup(app.UIFigure);
            app.JointSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @JointSelectionButtonGroupSelectionChanged, true);
            app.JointSelectionButtonGroup.Title = 'Joint Selection';
            app.XaxisButton = uitogglebutton(app.JointSelectionButtonGroup);
            app.XaxisButton.Text = 'Xaxis';
            app.XaxisButton.Value = true;
            app.YaxisButton = uitogglebutton(app.JointSelectionButtonGroup);
            app.YaxisButton.Text = 'Yaxis';
            app.RotationButton = uitogglebutton(app.JointSelectionButtonGroup);
            app.RotationButton.Text = 'Rotation';
            app.IndenterButton = uitogglebutton(app.JointSelectionButtonGroup);
            app.IndenterButton.Text = 'Indenter';
            app.OnOffButton = uibutton(app.UIFigure, 'push');
            app.OnOffButton.ButtonPushedFcn = createCallbackFcn(app, @OnOffButtonPushed, true);
            app.OnOffButton.Text = 'OnOff';
            app.SetSpeedSliderLabel = uilabel(app.UIFigure);
            app.SetSpeedSliderLabel.Text = 'Set Speed';
            app.SetSpeedSlider = uislider(app.UIFigure);
            app.SetSpeedSlider.Limits = [0 2000];
            app.SetSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SetSpeedSliderValueChanged, true);
            app.StepsDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.StepsDistanceEditFieldLabel.Text = 'Steps(Distance)';
            app.StepsDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsDistanceEditField.ValueDisplayFormat = '%.0f';
            app.StepsDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @StepsDistanceEditFieldValueChanged, true);
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.COMListBox = uilistbox(app.UIFigure);
            app.COMListBox.Items = {};
            app.COMListBox.ValueChangedFcn = createCallbackFcn(app, @COMListBoxValueChanged, true);
            app.COMListBox.Value = {};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialConterTesterApp_copy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end